classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        InputPanel                     matlab.ui.container.Panel
        ConstantdataovertimeCheckBox   matlab.ui.control.CheckBox
        GotoOuputsButton               matlab.ui.control.Button
        ManualRefreshButton            matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        BuyPriceDropDown               matlab.ui.control.DropDown
        BuyPriceDropDownLabel          matlab.ui.control.Label
        SellpriceDropDown              matlab.ui.control.DropDown
        SellpriceDropDownLabel         matlab.ui.control.Label
        GenerationDataDropDown         matlab.ui.control.DropDown
        GenerationDataDropDownLabel    matlab.ui.control.Label
        ConsumptionDataDropDown        matlab.ui.control.DropDown
        ConsumptionDataDropDownLabel   matlab.ui.control.Label
        ActorsSpinner                  matlab.ui.control.Spinner
        ActorsSpinnerLabel             matlab.ui.control.Label
        PricingMechanismDropDown       matlab.ui.control.DropDown
        PricingMechanismDropDownLabel  matlab.ui.control.Label
        HorizonEditField               matlab.ui.control.NumericEditField
        HorizonLabel                   matlab.ui.control.Label
        RunButton                      matlab.ui.control.Button
        TimestepEditField              matlab.ui.control.NumericEditField
        TimestepLabel                  matlab.ui.control.Label
        CplatformEditField             matlab.ui.control.NumericEditField
        C_plaformLabel                 matlab.ui.control.Label
        PgridEditField                 matlab.ui.control.NumericEditField
        P_GridLabel                    matlab.ui.control.Label
        CfeedbackEditField             matlab.ui.control.NumericEditField
        C_FeedbackFeeLabel             matlab.ui.control.Label
        CnetworkfeeEditField           matlab.ui.control.NumericEditField
        C_NetwrokFeeLabel              matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        OutputsPanel                   matlab.ui.container.Panel
        Spinner                        matlab.ui.control.Spinner
        SpinnerLabel                   matlab.ui.control.Label
        Switch                         matlab.ui.control.Switch
        SwitchLabel                    matlab.ui.control.Label
        UITable3                       matlab.ui.control.Table
        GotoInputsButton               matlab.ui.control.Button
        UITable2                       matlab.ui.control.Table
        UIAxes3                        matlab.ui.control.UIAxes
    end


    methods (Access = private)
        
    
 function updateInputTable(app)

    n = app.ActorsSpinner.Value;

    standardConsumption = [0 0 0 1.2 1.0 1.5 0.9];
    standardGeneration  = [1.5 1 0.8 0 0 0 0];
    standardBuy         = [0 0 0 0.28 0.22 0.18 0.16];
    standardSell        = [0.12 0.15 0.10 0 0 0 0];

    % Make sure standard vectors are long enough
    standardConsumption = standardConsumption(1:n);
    standardGeneration  = standardGeneration(1:n);
    standardBuy         = standardBuy(1:n);
    standardSell        = standardSell(1:n);

    % Keep existing table data if possible
    data = app.UITable.Data;

    if isempty(data) || size(data,1) ~= 4 || size(data,2) ~= n
        data = zeros(4,n);
    end

    % Row 1: Consumption
    switch app.ConsumptionDataDropDown.Value
        case 'Standard'
            data(1,:) = standardConsumption;
        case 'Zero'
            data(1,:) = zeros(1,n);
        case 'Custom'
            % Keep existing values
    end

    % Row 2: Generation
    switch app.GenerationDataDropDown.Value
        case 'Standard'
            data(2,:) = standardGeneration;
        case 'Zero'
            data(2,:) = zeros(1,n);
        case 'Custom'
            % Keep existing values
    end

    % Row 3: Buy price
    switch app.BuyPriceDropDown.Value
        case 'Standard'
            data(3,:) = standardBuy;
        case 'Zero'
            data(3,:) = zeros(1,n);
        case 'Custom'
            % Keep existing values
    end

    % Row 4: Sell price
    switch app.SellpriceDropDown.Value
        case 'Standard'
            data(4,:) = standardSell;
        case 'Zero'
            data(4,:) = zeros(1,n);
        case 'Custom'
            % Keep existing values
    end

    app.UITable.Data = data;

    % Labels
    colNames = cell(1,n);
    for i = 1:n
        colNames{i} = ['Actor ' num2str(i)];
    end

    app.UITable.ColumnName = colNames;
    app.UITable.RowName = {'Consumption','Generation','Buy price','Sell price'};

    % IMPORTANT:
    % suitable ColumnEditable works per column, not per row.
    % So if ANY row is Custom, the table columns become editable.
    isAnyCustom = strcmp(app.ConsumptionDataDropDown.Value,'Custom') || ...
                  strcmp(app.GenerationDataDropDown.Value,'Custom') || ...
                  strcmp(app.BuyPriceDropDown.Value,'Custom') || ...
                  strcmp(app.SellpriceDropDown.Value,'Custom');

    app.UITable.ColumnEditable = repmat(isAnyCustom,1,n);

end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                     updateInputTable(app);
    app.InputPanel.Visible = 'on';
    app.OutputsPanel.Visible = 'off';
        end

        % Callback function: not associated with a component
        function RunButtonPushed(app, event)

    inputs.P_grid = app.PgridEditField.Value;
    inputs.C_feedback = app.CfeedbackEditField.Value;
    inputs.C_networkfee = app.CnetworkfeeEditField.Value;
    inputs.C_platform = app.CplatformEditField.Value;

    inputs.timestep = app.TimestepEditField.Value;
    inputs.horizon = app.HorizonEditField.Value;

    inputs.vecConsumption = [0 0 0 1.2 1.0 1.5 0.9];
    inputs.vecGeneration  = [1.5 1 0.8 0 0 0 0];
    inputs.vecBuy         = [0 0 0 0.28 0.22 0.18 0.16];
    inputs.vecSell        = [0.12 0.15 0.10 0 0 0 0];

    tableData = app.UITable.Data;
    inputs.vecConsumption = tableData(1,:);
    inputs.vecGeneration  = tableData(2,:);
    inputs.vecBuy         = tableData(3,:);
    inputs.vecSell        = tableData(4,:);

    inputs.pricingMechanism = app.PricingMechanismDropDown.Value;

    results = runP2PModel2(inputs);

    app.Spinner.Limits = [1 length(results.timeVec)];
    app.Spinner.RoundFractionalValues = 'on';

    app.UITable2.Data = results.tradingArray(:,:,1);
    app.UITable2.ColumnName = {'Seller','Buyer','Energy','Price'};

    plot(app.UIAxes, results.timeVec, results.vecP2P);
    title(app.UIAxes, 'P2P traded energy');

    plot(app.UIAxes2, results.timeVec, results.vecClearPrice);
    title(app.UIAxes2, 'Clearing price');

    plot(app.UIAxes3, results.timeVec, results.vecGridImport);
    hold(app.UIAxes3, 'on');
    plot(app.UIAxes3, results.timeVec, results.vecGridExport);
    hold(app.UIAxes3, 'off');
  
    legend(app.UIAxes3, 'Import', 'Export');
    title(app.UIAxes3, 'Grid exchange');

value = app.ConstantdataovertimeCheckBox.Value;

if value == 1

    app.UIAxes.Visible = 'off';
    app.UIAxes2.Visible = 'off';
    app.UIAxes3.Visible = 'off';

    cla(app.UIAxes)
    cla(app.UIAxes2)
    cla(app.UIAxes3)

    legend(app.UIAxes,'off');
    legend(app.UIAxes2,'off');
    legend(app.UIAxes3,'off');


else

    app.UIAxes.Visible = 'on';
    app.UIAxes2.Visible = 'on';
    app.UIAxes3.Visible = 'on';

end
           arrayActor = results.arrayActor;
           tradingArray = results.tradingArray;

switch app.Switch.Value

    case 'Initial timestep'

        j = app.Spinner.Value;

        p2p_kWh = results.vecP2P(j);
        gridImport_kWh = results.vecGridImport(j);
        gridExport_kWh = results.vecGridExport(j);

        paid_EUR = sum(arrayActor(6,:,j));
        received_EUR = sum(arrayActor(7,:,j));

        clearingPrice = results.vecClearPrice(j);

        dsoRevenue_EUR = sum(arrayActor(11,:,j));
        platformRevenue_EUR = sum(arrayActor(12,:,j));
        largerGridReference_EUR = sum(arrayActor(13,:,j));

          j = round(app.Spinner.Value);

        ledgerData = tradingArray(:,:,j);

        % Remove empty rows
        ledgerData = ledgerData(ledgerData(:,3) > 0,:);


    case 'Cumulative'

        p2p_kWh = sum(results.vecP2P);
        gridImport_kWh = sum(results.vecGridImport);
        gridExport_kWh = sum(results.vecGridExport);

        paid_EUR = sum(arrayActor(6,:,:), 'all');
        received_EUR = sum(arrayActor(7,:,:), 'all');

        clearingPrices = results.vecClearPrice(results.vecClearPrice > 0);

        if isempty(clearingPrices)
            clearingPrice = 0;
        else
            clearingPrice = mean(clearingPrices);
        end

        dsoRevenue_EUR = sum(arrayActor(11,:,:), 'all');
        platformRevenue_EUR = sum(arrayActor(12,:,:), 'all');
        largerGridReference_EUR = sum(arrayActor(13,:,:), 'all');

             ledgerData = [];

        for j = 1:size(tradingArray,3)

            stepData = tradingArray(:,:,j);

            % Remove empty rows
            stepData = stepData(stepData(:,3) > 0,:);

            if ~isempty(stepData)

                % Add timestep as first column
                stepData = [j * ones(size(stepData,1),1), stepData];

                ledgerData = [ledgerData; stepData];

            end
        end
       app.UITable2.ColumnName = {'Timestep','Seller','Buyer','Energy','Price'};
if strcmp(app.Switch.Value, 'Initial timestep')

    app.UITable2.Data = ledgerData;
    app.UITable2.ColumnName = {'Seller','Buyer','Energy','Price'};

else

    app.UITable2.Data = ledgerData;

end
end

app.UITable3.Data = [
    p2p_kWh, ...
    gridImport_kWh, ...
    gridExport_kWh, ...
    paid_EUR, ...
    received_EUR, ...
    clearingPrice, ...
    dsoRevenue_EUR, ...
    platformRevenue_EUR, ...
    largerGridReference_EUR
];

app.UITable3.ColumnName = { ...
    'P2P kWh', ...
    'Grid Import', ...
    'Grid Export', ...
    'Money Paid €', ...
    'Money Received €', ...
    'Clearing Price €/kWh', ...
    'DSO Revenue €', ...
    'Platform Revenue €', ...
    'Grid-only Reference €'};
        end

        % Callback function: not associated with a component
        function ActorsSpinnerValueChanged(app, event)
 n = app.ActorsSpinner.Value;

    colNames = cell(1,n);
    for i = 1:n
        colNames{i} = ['Actor ' num2str(i)];
    end

    app.UITable.ColumnName = colNames;
    app.UITable.RowName = {'Consumption','Generation','Buy price','Sell price'};
    app.UITable.Data = zeros(4,n);
            updateInputTable(app);
        end

        % Callback function: not associated with a component
        function UITableCellEdit(app, event)
 val = event.NewData;

    % Check if value is numeric
    if ~isnumeric(val) || isnan(val)
        % Revert to previous value
        data = app.UITable.Data;
        data(event.Indices(1), event.Indices(2)) = event.PreviousData;
        app.UITable.Data = data;

        % Show warning
        uialert(app.UIFigure, 'Only numeric values are allowed', 'Invalid input');
    end


        end

        % Value changed function: ConsumptionDataDropDown
        function ConsumptionDataDropDownValueChanged(app, event)
         updateInputTable(app);
            
        end

        % Value changed function: GenerationDataDropDown
        function GenerationDataDropDownValueChanged(app, event)
              updateInputTable(app);
            
        end

        % Value changed function: SellpriceDropDown
        function SellpriceDropDownValueChanged(app, event)
              updateInputTable(app);
            
        end

        % Value changed function: BuyPriceDropDown
        function BuyPriceDropDownValueChanged(app, event)
               updateInputTable(app);
            
        end

        % Callback function: not associated with a component
        function ManualRefreshButtonPushed(app, event)
                updateInputTable(app);
        end

        % Button pushed function: GotoOuputsButton
        function GotoOuputsButtonPushed(app, event)

    RunButtonPushed(app, event);
    % switch view
    app.InputPanel.Visible = 'off';
    app.OutputsPanel.Visible = 'on';

        end

        % Button pushed function: GotoInputsButton
        function GotoInputsButtonPushed(app, event)
            
    app.OutputsPanel.Visible = 'off';
    app.InputPanel.Visible = 'on';
        end

        % Callback function: not associated with a component
        function ConstantdataovertimeCheckBoxValueChanged(app, event)
            value = app.ConstantdataovertimeCheckBox.Value;
            if value == 1
                app.UIAxes.Visible = 'off';
                app.UIAxes2.Visible = 'off';
                app.UIAxes3.Visible = 'off';

                cla(app.UIAxes)
                cla(app.UIAxes2)
                cla(app.UIAxes3)
                legend(app.UIAxes,'off');
                legend(app.UIAxes2,'off');
                legend(app.UIAxes3,'off');
            elseif value == 0
                app.UIAxes.Visible = 'on';
                app.UIAxes2.Visible = 'on';
                app.UIAxes3.Visible = 'on';
            end
        end

        % Callback function: not associated with a component
        function GotoOuputsButtonPushed2(app, event)
            
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
  if strcmp(app.Switch.Value, 'Initial timestep')
        RunButtonPushed(app, event);
    end
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)


    if strcmp(app.Switch.Value, 'Initial timestep')
        app.Spinner.Enable = 'on';
    else
        app.Spinner.Enable = 'off';
    end

    RunButtonPushed(app, event);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OutputsPanel = uipanel(app.UIFigure);
            app.OutputsPanel.Title = 'OutputsPanel';
            app.UIAxes = uiaxes(app.OutputsPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.OutputsPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.OutputsPanel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UITable2 = uitable(app.OutputsPanel);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.GotoInputsButton = uibutton(app.OutputsPanel, 'push');
            app.GotoInputsButton.ButtonPushedFcn = createCallbackFcn(app, @GotoInputsButtonPushed, true);
            app.GotoInputsButton.Text = 'Go to Inputs';
            app.UITable3 = uitable(app.OutputsPanel);
            app.UITable3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable3.RowName = {};
            app.SwitchLabel = uilabel(app.OutputsPanel);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.OutputsPanel, 'slider');
            app.Switch.Items = {'Initial timestep', 'Cumulative'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Initial timestep';
            app.SpinnerLabel = uilabel(app.OutputsPanel);
            app.SpinnerLabel.Text = 'Spinner';
            app.Spinner = uispinner(app.OutputsPanel);
            app.Spinner.Limits = [1 48];
            app.Spinner.RoundFractionalValues = 'on';
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Value = 1;
            app.InputPanel = uipanel(app.UIFigure);
            app.InputPanel.Title = 'Input Panel';
            app.C_NetwrokFeeLabel = uilabel(app.InputPanel);
            app.C_NetwrokFeeLabel.Text = 'C_NetworkFee';
            app.CnetworkfeeEditField = uieditfield(app.InputPanel, 'numeric');
            app.CnetworkfeeEditField.Value = 0.04;
            app.C_FeedbackFeeLabel = uilabel(app.InputPanel);
            app.C_FeedbackFeeLabel.Text = 'C_FeedbackFee';
            app.CfeedbackEditField = uieditfield(app.InputPanel, 'numeric');
            app.CfeedbackEditField.Value = 0.08;
            app.P_GridLabel = uilabel(app.InputPanel);
            app.P_GridLabel.Text = 'P_Grid';
            app.PgridEditField = uieditfield(app.InputPanel, 'numeric');
            app.PgridEditField.Value = 0.3;
            app.C_plaformLabel = uilabel(app.InputPanel);
            app.C_plaformLabel.Text = 'C_plaform';
            app.CplatformEditField = uieditfield(app.InputPanel, 'numeric');
            app.CplatformEditField.Value = 0.01;
            app.TimestepLabel = uilabel(app.InputPanel);
            app.TimestepLabel.Text = 'Timestep';
            app.TimestepEditField = uieditfield(app.InputPanel, 'numeric');
            app.TimestepEditField.Value = 0.5;
            app.RunButton = uibutton(app.InputPanel, 'push');
            app.RunButton.Text = 'Run';
            app.HorizonLabel = uilabel(app.InputPanel);
            app.HorizonLabel.Text = 'Horizon';
            app.HorizonEditField = uieditfield(app.InputPanel, 'numeric');
            app.HorizonEditField.Value = 24;
            app.PricingMechanismDropDownLabel = uilabel(app.InputPanel);
            app.PricingMechanismDropDownLabel.Text = 'PricingMechanism';
            app.PricingMechanismDropDown = uidropdown(app.InputPanel);
            app.PricingMechanismDropDown.Items = {'MarketClearing', 'Option 2', 'Option 3', 'Option 4'};
            app.PricingMechanismDropDown.Value = 'MarketClearing';
            app.ActorsSpinnerLabel = uilabel(app.InputPanel);
            app.ActorsSpinnerLabel.Text = 'Actors';
            app.ActorsSpinner = uispinner(app.InputPanel);
            app.ActorsSpinner.Limits = [0 100];
            app.ActorsSpinner.Value = 7;
            app.ConsumptionDataDropDownLabel = uilabel(app.InputPanel);
            app.ConsumptionDataDropDownLabel.Text = 'Consumption Data';
            app.ConsumptionDataDropDown = uidropdown(app.InputPanel);
            app.ConsumptionDataDropDown.Items = {'Standard', 'Custom', 'Zero', 'Option 4'};
            app.ConsumptionDataDropDown.ValueChangedFcn = createCallbackFcn(app, @ConsumptionDataDropDownValueChanged, true);
            app.ConsumptionDataDropDown.Value = 'Standard';
            app.GenerationDataDropDownLabel = uilabel(app.InputPanel);
            app.GenerationDataDropDownLabel.Text = 'Generation Data';
            app.GenerationDataDropDown = uidropdown(app.InputPanel);
            app.GenerationDataDropDown.Items = {'Standard', 'Custom', 'Zero', 'Option 4'};
            app.GenerationDataDropDown.ValueChangedFcn = createCallbackFcn(app, @GenerationDataDropDownValueChanged, true);
            app.GenerationDataDropDown.Value = 'Standard';
            app.SellpriceDropDownLabel = uilabel(app.InputPanel);
            app.SellpriceDropDownLabel.Text = 'Sell price';
            app.SellpriceDropDown = uidropdown(app.InputPanel);
            app.SellpriceDropDown.Items = {'Standard', 'Custom', 'Zero', 'Option 4'};
            app.SellpriceDropDown.ValueChangedFcn = createCallbackFcn(app, @SellpriceDropDownValueChanged, true);
            app.SellpriceDropDown.Value = 'Standard';
            app.BuyPriceDropDownLabel = uilabel(app.InputPanel);
            app.BuyPriceDropDownLabel.Text = 'Buy Price';
            app.BuyPriceDropDown = uidropdown(app.InputPanel);
            app.BuyPriceDropDown.Items = {'Standard', 'Custom', 'Zero', 'Option 4'};
            app.BuyPriceDropDown.ValueChangedFcn = createCallbackFcn(app, @BuyPriceDropDownValueChanged, true);
            app.BuyPriceDropDown.Value = 'Standard';
            app.UITable = uitable(app.InputPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.ManualRefreshButton = uibutton(app.InputPanel, 'push');
            app.ManualRefreshButton.Text = 'Manual Refresh';
            app.GotoOuputsButton = uibutton(app.InputPanel, 'push');
            app.GotoOuputsButton.ButtonPushedFcn = createCallbackFcn(app, @GotoOuputsButtonPushed, true);
            app.GotoOuputsButton.Text = 'Go to Ouputs';
            app.ConstantdataovertimeCheckBox = uicheckbox(app.InputPanel);
            app.ConstantdataovertimeCheckBox.Text = 'Constant  data over time';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end